classdef COVIDDataVisualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19DataVisualizationUIFigure  matlab.ui.Figure
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        DailyButton                matlab.ui.control.RadioButton
        CumulativeButton           matlab.ui.control.RadioButton
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        BothButton                 matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        CasesButton                matlab.ui.control.RadioButton
        AverageSlider              matlab.ui.control.Slider
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        CountryLabel               matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
        StateorRegionLabel         matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        globe Place  % data of all countries added together
        countryToIndex  containers.Map  % map country name to an index
        countryList  Place  % vector of country objects
        timeSeries  % vector of ordered dates for which data is available
    end
    
    methods (Access = private)
        
        function load_covid_data(app, covid_data)
            % create required objects and store then in private properties
            cIdx = 0;
            r = size(covid_data, 1);
            c = size(covid_data, 2);
            app.timeSeries = string(covid_data(1, 3:c));
            pl_names = string.empty(0,r-1);  % names of countries
            pl_obj = Place.empty(0, r-1);  % list of country objects
            app.globe = Place("Global");
            
            for place = 2:r
                country_name = covid_data{place, 1};
                state_name = covid_data{place, 2};
                k = covid_data(1, 3:c);  % dates
                v = covid_data(place, 3:c);  % row vectors
                data_map = containers.Map(k, v);
                % first time seeing this place
                if ~isequal(country_name, covid_data{place-1, 1})    
                    cIdx = cIdx + 1;
                    pl_names(cIdx) = country_name;
                    pl_obj(cIdx) = Place(country_name, data_map); 
                    if isempty(app.globe.Data)
                        app.globe.Data = data_map;
                    else
                        for i = 1:length(app.timeSeries)
                            app.globe.Data(app.timeSeries(i)) = app.globe.Data(app.timeSeries(i)) + v{i};
                        end
                    end                    
                else
                    % country is repeated, repeated rows are for
                    % states/regions
                    state_obj = Place(state_name, data_map); 
                    state_obj.Within = pl_obj(cIdx);
                    pl_obj(cIdx).Contains{end+1} = state_obj;
                end
            end
            app.countryToIndex = containers.Map(pl_names, 1:length(pl_names));
            app.countryList = pl_obj;
            %disp('finished load');
            
            % plot data
            dataToPlot = app.DatatoPlotButtonGroup.SelectedObject.Text;
            plotOption = app.OptionButtonGroup.SelectedObject.Text;
            plotFilt = app.AverageSlider.Value;
            plot_place_data(app, app.globe, dataToPlot, plotOption, plotFilt);
        end
        
        function plot_place_data(app, place_obj, dataToPlot, plotOption, plotFilt) 
            % plot the data associated with given place object
            % (country/state) using the given plot options
            
            % columns: 1-cumulative deaths, 2-cumulative cases
            data_columns = zeros(length(app.timeSeries), 2);
            idx = 1;
            for date = app.timeSeries
                data_columns(idx,:) = place_obj.Data(date);
                idx = idx + 1;
            end            
            
            % apply filtering to data
            data_columns = movmean(data_columns, [plotFilt-1 0], 1);
            
            % convert string dates into date time objects for plotting
            t = datetime(app.timeSeries, "Format","MM/dd/yy");
            cla(app.UIAxes, "reset");  
            if plotOption == "Daily"
                daily_data = zeros(size(data_columns, 1)-1, 2);
                daily_data(:,1) = diff(data_columns(:,1));
                daily_data(:,2) = diff(data_columns(:,2));
            end
            
            if dataToPlot == "Cases" || dataToPlot == "Both"
                if plotOption == "Cumulative"
                    bar(app.UIAxes, t, data_columns(:,1), 'b');     
                elseif plotOption == "Daily"
                    bar(app.UIAxes, t(2:end), daily_data(:,1), 'b');
                end
            end
            
            if dataToPlot == "Both"
                hold(app.UIAxes, "on");
                yyaxis(app.UIAxes, "right");
            end
            
            if dataToPlot == "Deaths" || dataToPlot == "Both"
                if plotOption == "Cumulative"
                    plot(app.UIAxes, t, data_columns(:,2), '-r');
                elseif plotOption == "Daily"
                    plot(app.UIAxes, t(2:end), daily_data(:,2), '-r');
                end
            end
            
            % axes display settings
            xlim(app.UIAxes, [t(1), t(end)]);
            axis(app.UIAxes, "auto y");
            ui_ruler = app.UIAxes.YAxis;
            for i = 1:length(ui_ruler)
                ui_ruler(i).Exponent = 0;
            end
            
            titlesub_1 = plotOption;
            titlesub_2 = dataToPlot;
            if titlesub_2 == "Both"
                titlesub_2 = "Cases and Deaths";
            end
            if ~(place_obj.Name == "Global")
                titlesub_3 = "in " + place_obj.Name;
            else
                titlesub_3 = "Globally";
            end 
            title(app.UIAxes, sprintf("%s Number of %s %s", titlesub_1, titlesub_2, titlesub_3));
            hold(app.UIAxes, "off");
            %disp('finished plot');
        end
        
        function obj = getselectedplace(app)
            % get the place object associated with current selection 
            country = app.CountryListBox.Value;
            state = app.StateorRegionListBox.Value;            
            if state == "All"
                if ~(country == "Global")
                    obj = app.countryList(app.countryToIndex(country));
                else
                    obj = app.globe;
                end
            else
                states = app.countryList(app.countryToIndex(country)).Contains;
                for i = 1:length(states)
                    if isequal(states{i}.Name, state)
                        obj = states{i};
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat
            
            % create required objects
            app.load_covid_data(covid_data);
            
            % populate countryListBox
            app.CountryListBox.Items = ["Global", app.countryToIndex.keys()];
            
            % populate stateorRegionListBox
            app.StateorRegionListBox.Items = ["All"];
            
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            value = app.CountryListBox.Value;  % country name
            
            if ~(value == "Global")
                place_obj = app.countryList(app.countryToIndex(value)); 
                
                % update states list box
                states = string.empty(0, length(place_obj.Contains)+1);
                states(1) = "All";               
                for s = 1:length(place_obj.Contains)
                    states(s+1) = place_obj.Contains{s}.Name;
                end
                app.StateorRegionListBox.Items = states;
            else
                place_obj = app.global;
            end                     
            % plot data
            dataToPlot = app.DatatoPlotButtonGroup.SelectedObject.Text;
            plotOption = app.OptionButtonGroup.SelectedObject.Text;
            plotFilt = app.AverageSlider.Value;
            plot_place_data(app, place_obj, dataToPlot, plotOption, plotFilt);
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            place_obj = getselectedplace(app);
            plot_place_data(app, place_obj, app.DatatoPlotButtonGroup.SelectedObject.Text, app.OptionButtonGroup.SelectedObject.Text, app.AverageSlider.Value);
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            selectedButton = app.DatatoPlotButtonGroup.SelectedObject;                
            place_obj = getselectedplace(app);
            plot_place_data(app, place_obj, selectedButton.Text, app.OptionButtonGroup.SelectedObject.Text, app.AverageSlider.Value);
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.OptionButtonGroup.SelectedObject;
            place_obj = getselectedplace(app);
            plot_place_data(app, place_obj, app.DatatoPlotButtonGroup.SelectedObject.Text, selectedButton.Text, app.AverageSlider.Value);
        end

        % Value changed function: AverageSlider
        function AverageSliderValueChanged(app, event)
            value = app.AverageSlider.Value;
            place_obj = getselectedplace(app);
            plot_place_data(app, place_obj, app.DatatoPlotButtonGroup.SelectedObject.Text, app.OptionButtonGroup.SelectedObject.Text, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19DataVisualizationUIFigure = uifigure('Visible', 'off');
            app.COVID19DataVisualizationUIFigure.Name = 'COVID-19 Data Visualization (Data source: Johns Hopkins University)';
            app.UIAxes = uiaxes(app.COVID19DataVisualizationUIFigure);
            title(app.UIAxes, 'COVID-19 DATA')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StateorRegionLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.StateorRegionLabel.Text = 'State or Region:';
            app.StateorRegionListBox = uilistbox(app.COVID19DataVisualizationUIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.CountryLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.CountryLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.COVID19DataVisualizationUIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.AveragedofdaysSliderLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.AveragedofdaysSliderLabel.Text = {'Averaged '; '# of days'};
            app.AverageSlider = uislider(app.COVID19DataVisualizationUIFigure);
            app.AverageSlider.Limits = [1 15];
            app.AverageSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AverageSlider.ValueChangedFcn = createCallbackFcn(app, @AverageSliderValueChanged, true);
            app.AverageSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.AverageSlider.Value = 1;
            app.DatatoPlotButtonGroup = uibuttongroup(app.COVID19DataVisualizationUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.COVID19DataVisualizationUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            % Show the figure after all components are created
            app.COVID19DataVisualizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COVIDDataVisualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19DataVisualizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19DataVisualizationUIFigure)
        end
    end
end